classdef GrinCalcApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HomeMenu                        matlab.ui.container.Menu
        SaveProjectMenu                 matlab.ui.container.Menu
        SaveProjectAsMenu               matlab.ui.container.Menu
        LoadProjectMenu                 matlab.ui.container.Menu
        StackMenu                       matlab.ui.container.Menu
        LineMenu                        matlab.ui.container.Menu
        FigureMenu                      matlab.ui.container.Menu
        SaveFigureMenu                  matlab.ui.container.Menu
        TestStopButton                  matlab.ui.control.Button
        Console                         matlab.ui.control.TextArea
        TotalLayers                     matlab.ui.control.Label
        ToolMenuLabel                   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        StackTab                        matlab.ui.container.Tab
        RecalculateOverlayButton        matlab.ui.control.Button
        RefractiveIndexOverlayCheckBox  matlab.ui.control.CheckBox
        OutsiderefractiveindexEditField  matlab.ui.control.NumericEditField
        OutsiderefractiveindexEditFieldLabel  matlab.ui.control.Label
        WavelengthnmEditField           matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        ShowLines                       matlab.ui.control.CheckBox
        SupportLinesLabel               matlab.ui.control.Label
        SupportLineTable                matlab.ui.control.Table
        CreateSupportLineButton         matlab.ui.control.Button
        SamplingLinesLabel              matlab.ui.control.Label
        SamplingLineTable               matlab.ui.control.Table
        CreateSamplingLineButton        matlab.ui.control.Button
        ShowCropRec                     matlab.ui.control.CheckBox
        ShowCropedIm                    matlab.ui.control.CheckBox
        CropStackButton                 matlab.ui.control.Button
        ImportImagesButton              matlab.ui.control.Button
        LineTab                         matlab.ui.container.Tab
        SetSavGolayZoneButton           matlab.ui.control.Button
        SavGolayZone                    matlab.ui.control.NumericEditField
        SavGolayZoneEditFieldLabel      matlab.ui.control.Label
        PlotOptionsPanel                matlab.ui.container.Panel
        ShowPeaks                       matlab.ui.control.CheckBox
        ShowalllayersCheckBox           matlab.ui.control.CheckBox
        NormalizedDataCheckBox          matlab.ui.control.CheckBox
        NormalizeDataButton             matlab.ui.control.Button
        FindEdgeMinButton               matlab.ui.control.Button
        FindPeaksButton                 matlab.ui.control.Button
        FindCenterButton                matlab.ui.control.Button
        FindEdgeButton                  matlab.ui.control.Button
        FlipDataButton                  matlab.ui.control.Button
        LineDataTable                   matlab.ui.control.Table
        LineInterpGroup                 matlab.ui.container.ButtonGroup
        AllButton                       matlab.ui.control.RadioButton
        SavGoButton                     matlab.ui.control.RadioButton
        GaussianButton                  matlab.ui.control.RadioButton
        RawButton                       matlab.ui.control.RadioButton
        PhaseTab                        matlab.ui.container.Tab
        PolynomialgradeDropDown         matlab.ui.control.DropDown
        PolynomialgradeDropDownLabel    matlab.ui.control.Label
        MinLayerEditField               matlab.ui.control.NumericEditField
        MinLayerEditFieldLabel          matlab.ui.control.Label
        MaxLayerEditField               matlab.ui.control.NumericEditField
        MaxLayerEditFieldLabel          matlab.ui.control.Label
        UpperRestrField                 matlab.ui.control.NumericEditField
        UpperEditFieldLabel             matlab.ui.control.Label
        UserestraintsCheckBox           matlab.ui.control.CheckBox
        LowerRestrField                 matlab.ui.control.NumericEditField
        LowerLabel                      matlab.ui.control.Label
        FitTypeButtonGroup              matlab.ui.container.ButtonGroup
        expoFitButton                   matlab.ui.control.RadioButton
        polyFitButton                   matlab.ui.control.RadioButton
        FitPhaseProfileButton           matlab.ui.control.Button
        ClearOutside                    matlab.ui.control.Button
        LayerPhaseShift                 matlab.ui.control.NumericEditField
        PhaseshiftforeachlayerLabel     matlab.ui.control.Label
        ClearAllPeaksButton             matlab.ui.control.Button
        SelectPeaksButton               matlab.ui.control.Button
        EdgeExclusionEditField          matlab.ui.control.NumericEditField
        EdgeExclusionEditFieldLabel     matlab.ui.control.Label
        CenterExclusionEditField        matlab.ui.control.NumericEditField
        CenterExclusionEditFieldLabel   matlab.ui.control.Label
        PickEdgeExclButton              matlab.ui.control.Button
        PickCenterExclButton            matlab.ui.control.Button
        PhaseTable                      matlab.ui.control.Table
        TracingthresholdsPanel          matlab.ui.container.Panel
        MinPeakNumberEditField          matlab.ui.control.NumericEditField
        MinPeakNumberEditFieldLabel     matlab.ui.control.Label
        DistanceThresholdEditField      matlab.ui.control.NumericEditField
        DistanceThresholdEditFieldLabel  matlab.ui.control.Label
        RemovePeaksButton               matlab.ui.control.Button
        ShowPlotButtonGroup             matlab.ui.container.ButtonGroup
        PeakSelectButton                matlab.ui.control.RadioButton
        PhaseGradientButton             matlab.ui.control.RadioButton
        PeakTraceButton                 matlab.ui.control.RadioButton
        nIndexTab                       matlab.ui.container.Tab
        GetValuesFromStackButton        matlab.ui.control.Button
        CalculateRefractiveIndexButton  matlab.ui.control.Button
        OutsiderefractiveindexEditField_2  matlab.ui.control.NumericEditField
        OutsiderefractiveindexEditField_2Label  matlab.ui.control.Label
        WavelengthnmEditField_2         matlab.ui.control.NumericEditField
        WavelengthnmEditField_2Label    matlab.ui.control.Label
        CalibrationTab                  matlab.ui.container.Tab
        PixelSize                       matlab.ui.control.NumericEditField
        PixelSizeEditFieldLabel         matlab.ui.control.Label
        CalibDist                       matlab.ui.control.NumericEditField
        DistanceumEditFieldLabel        matlab.ui.control.Label
        CalibrateImageButton            matlab.ui.control.Button
        ImportCalibrationImageButton    matlab.ui.control.Button
        SamplingLineSpinner             matlab.ui.control.Spinner
        SamplingLineSpinnerLabel        matlab.ui.control.Label
        LayerSpinner                    matlab.ui.control.Spinner
        LayerSpinnerLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        SampLineTableMenue              matlab.ui.container.ContextMenu
        DeleteSelectedLineMenu          matlab.ui.container.Menu
        SupLinesTableMenue              matlab.ui.container.ContextMenu
        DeleteSelectedSupLineMenu       matlab.ui.container.Menu
    end

    properties (Access = public)
        lens = nan;
%         lineInd = nan;
        supLineInd = nan;
%         lineType = nan;
        consoleText = {'>> Startup'};
        saveLabel = nan;
        awn = nan;
        listenForKey = false;
        keyIn = '';
        calibrationLine = nan;
        projectName = 'Untiteled Project';
        signalFromApp = false;
        currentLineInd = nan;
        lineData = nan;
        
%         tdata = table(dates,m,'VariableNames',{'Date','Measurement'});
    end
    
    methods (Access = public)
        checkImportedData(app)
        
        uppdateApp(app)
        uppdateImstackImage(app)
        uppdateLinePlot(app)
        uppdateLineData(app)
        uppdatePhasePlot(app)
        uppdateRefractiveIndexPlot(app)
        
        createLine(app,style)
        deleteLine(app,style)
        lineSpinnerChange(app)
        
        findEdge(app)
        findCenter(app)
        
        selectPeaks(app)
        estimatePhase(app,serie)
        clearOustidePeaks(app)
%         interpolateRefractiveIndex(app)
        
        overwriteValue(app, cVar)
        
        function addTwoConsole(app,tx)
            tx = ['>>',' ',tx];
            txLines = length(app.consoleText);
            app.consoleText{txLines + 1} = tx;
            app.ConsoleValueChanged
        end
    end


%     >> h = app.TabGroup.Children(2);
% You can then directly assign it to SelectedTab property.
% >> app.TabGroup.SelectedTab = h;

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = app.projectName;
            app.lens = Imstack;
            app.ConsoleValueChanged()
            ldata = {'Center Distance', nan ; 'Edge Distance', nan; 'Edge Min Layer', nan; 'Edge Max Layer', nan};
            app.LineDataTable.Data = [app.LineDataTable.Data;ldata];
%             pdata = {'Group', nan, 'P_ind', nan, 'Phase Shift', nan, 'Layer', nan,'Interpolation',nan,'Ecxlude',nan};
%             pdata = {nan,nan,nan,nan,nan,nan};
%             app.PhaseTable.Data = [app.PhaseTable.Data;pdata];
%             zdata = {'Interpolation',nan,'Start',nan,'End',nan};
%             app.IspanTable.Data = [app.IspanTable.Data;zdata];
            
%             infile = importdata('C:\Users\Mikael\MATLAB\Projects\GRIN\saves\test1.mat');
%             app.lens = infile;
%             app.checkImportedData()
%             app.uppdateApp()
%             app.UIFigure.Name = 'test1';
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if app.listenForKey
                key = event.Key;
                if ismember(key,{'q','return'})
                    app.listenForKey = false;
                    app.keyIn = key;
                    uiresume(app.UIFigure)
                end
            end           
        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
            if isnan(app.saveLabel)
                app.SaveProjectAsMenuSelected
            else
                lensVar = app.lens;
                save(app.saveLabel,'lensVar')
            end
        end

        % Menu selected function: SaveProjectAsMenu
        function SaveProjectAsMenuSelected(app, event)
            [file,path] = uiputfile('saves\*.mat');
            lensVar = app.lens;
            save(strcat(path,file),'lensVar')
            app.saveLabel = strcat(path,file);
            app.UIFigure.Name = file(1:end-4);
            app.projectName = file(1:end-4);
        end

        % Menu selected function: LoadProjectMenu
        function LoadProjectMenuSelected(app, event)
            [filename, path] = uigetfile('*.mat','Load save file','saves\');
            app.saveLabel = strcat(path,filename);
            infile = importdata(app.saveLabel);
            app.lens = infile;
            app.checkImportedData()
            app.UIFigure.Name = filename(1:end-4);
            app.projectName = filename(1:end-4);
        end

        % Button pushed function: ImportImagesButton
        function ImportImagesButtonPushed(app, event)
            app.lens.importStack()
            app.lens.dispRawImage(1,app.UIAxes)
            app.LayerSpinner.Limits = [1,app.lens.imNums];
            app.LayerSpinner.Enable = true;
            app.TotalLayers.Text = strcat('Total:',' ',num2str(app.lens.imNums));
            app.LayerPhaseShift.Value = 1/(app.lens.imNums-1);
        end

        % Button pushed function: CropStackButton
        function CropStackButtonPushed(app, event)
            app.addTwoConsole('Draw a cropping rectangle. Press "enter" when done or "Q" to cansel')
            app.listenForKey = true;
            roi = drawrectangle(app.UIAxes);
            uiwait(app.UIFigure)
            if isequal(app.keyIn,'return')
                app.lens.AppCropStack(roi)
                app.ShowCropedIm.Enable = true;
                app.ShowCropedIm.Value = true;
                app.ShowCropRec.Enable = true;
            else
                delete(roi)
            end
            app.keyIn = '';
        end

        % Button pushed function: CreateSupportLineButton
        function CreateSupportLineButtonPushed(app, event)
            app.createLine('support')            
        end

        % Button pushed function: CreateSamplingLineButton
        function CreateSamplingLineButtonPushed(app, event)
            app.createLine('sampling')
            app.lens.AppUppdateLineData(app.SamplingLineTable.Data)
        end

        % Callback function
        function ConsoleValueChanged(app, event)
            app.Console.Value = app.consoleText;
            scroll(app.Console,'bottom')
        end

        % Value changed function: ShowCropedIm
        function ShowCropedImValueChanged(app, event)
            app.uppdateImstackImage()
        end

        % Value changed function: ShowCropRec
        function ShowCropRecValueChanged(app, event)
            app.uppdateImstackImage()
        end

        % Cell selection callback: SamplingLineTable
        function SamplingLineTableCellSelection(app, event)
            val = app.SamplingLineTable.Data(event.Indices(1),1);
            app.SamplingLineSpinner.Value = val{1};
            app.uppdateApp()
        end

        % Cell selection callback: SupportLineTable
        function SupportLineTableCellSelection(app, event)
            val = app.SupportLineTable.Data(event.Indices(1),1);
            app.supLineInd = val{1};
        end

        % Menu selected function: DeleteSelectedSupLineMenu
        function DeleteSelectedSupLineMenuSelected(app, event)
            app.deleteLine('support')
        end

        % Menu selected function: DeleteSelectedLineMenu
        function DeleteSelectedLineMenuSelected(app, event)
            app.deleteLine('sampling')
            app.lens.AppUppdateLineData(app.SamplingLineTable.Data)
        end

        % Value changed function: SamplingLineSpinner
        function SamplingLineSpinnerValueChanged(app, event)
            app.lineSpinnerChange()
            app.uppdateApp()
        end

        % Value changed function: LayerSpinner
        function LayerSpinnerValueChanged(app, event)
            app.uppdateApp()            
        end

        % Cell edit callback: LineDataTable
        function LineDataTableCellEdit(app, event)
            val = event.Indices(1);
            switch val
                case 1
                    app.lens.mLines{app.SamplingLineSpinner.Value}.centerLine = app.LineDataTable.Data{1,2};
                    distToCenter = app.lens.mLines{app.SamplingLineSpinner.Value}.d-app.lens.mLines{app.SamplingLineSpinner.Value}.centerLine;
                    app.lens.mLines{app.SamplingLineSpinner.Value}.centerLineIndex = find(distToCenter > 0,1);
                case 2
                    app.lens.mLines{app.SamplingLineSpinner.Value}.leftEdge = app.LineDataTable.Data{2,2};
                    distToEdge = (app.lens.mLines{app.SamplingLineSpinner.Value}.d-app.lens.mLines{app.SamplingLineSpinner.Value}.leftEdge);
                    app.lens.mLines{app.SamplingLineSpinner.Value}.leftEdgeIndex = find(distToEdge > 0,1)-1;
                case 3
                    app.lens.mLines{app.SamplingLineSpinner.Value}.leftPhaseMin = app.LineDataTable.Data{3,2};
                    layerPhaseStep = 0.5/abs(app.LineDataTable.Data{4,2}-app.LineDataTable.Data{3,2});
                    app.lens.mLines{app.SamplingLineSpinner.Value}.layerPhaseStep = layerPhaseStep;
                case 4
                    app.lens.mLines{app.SamplingLineSpinner.Value}.leftPhaseMax = app.LineDataTable.Data{4,2};
                    layerPhaseStep = 0.5/abs(app.LineDataTable.Data{4,2}-app.LineDataTable.Data{3,2});
                    app.lens.mLines{app.SamplingLineSpinner.Value}.layerPhaseStep = layerPhaseStep;
            end
            app.uppdateApp()
        end

        % Button pushed function: NormalizeDataButton
        function NormalizeDataButtonPushed(app, event)
            if ~isempty(app.lens.mLines{app.SamplingLineSpinner.Value}.gaussPks)
                answer = uiconfirm(app.UIFigure,'This will remove the current peaks. Continue?','Normalize points','Icon','warning');
                if isequal(answer,'Cancel')
                    return
                end
            end            
            app.lens.mLines{app.SamplingLineSpinner.Value}.normalize_points;
            app.NormalizedDataCheckBox.Value = true;
            app.uppdateApp()
        end

        % Button pushed function: FlipDataButton
        function FlipDataButtonPushed(app, event)
            lineId = app.SamplingLineSpinner.Value;
            if ~isempty(app.lens.mLines{lineId}.gaussPks) || ~isnan(app.lens.mLines{lineId}.centerLine) || ~isnan(app.lens.mLines{lineId}.leftEdge) 
                answer = uiconfirm(app.UIFigure,'This will remove any peaks and lines. Continue?','Normalize points','Icon','warning');
                if isequal(answer,'Cancel')
                    return
                elseif isequal(answer,'OK')
                    app.lens.mLines{lineId}.AppFlipData()
                end
            else
                app.lens.mLines{lineId}.AppFlipData()
            end
            app.uppdateApp()
        end

        % Button pushed function: FindEdgeButton
        function FindEdgeButtonPushed(app, event)
            app.findEdge()
            app.uppdateApp()
        end

        % Selection changed function: LineInterpGroup
        function LineInterpGroupSelectionChanged(app, event)
            app.uppdateApp()
        end

        % Button pushed function: FindPeaksButton
        function FindPeaksButtonPushed(app, event)
            app.lens.mLines{app.SamplingLineSpinner.Value}.findPeaks()
            app.ShowPeaks.Value = true;
        end

        % Button pushed function: FindCenterButton
        function FindCenterButtonPushed(app, event)
            app.findCenter()
            app.uppdateApp()
        end

        % Button pushed function: FindEdgeMinButton
        function FindEdgeMinButtonPushed(app, event)
            if isnan(app.lens.mLines{app.SamplingLineSpinner.Value}.leftEdge)
                app.addTwoConsole('Error: The edge of the lens has not been defined') 
                return
            end
            app.lens.mLines{app.SamplingLineSpinner.Value}.find_minmax_slice()
            app.LineDataTable.Data{3,2} = app.lens.mLines{app.SamplingLineSpinner.Value}.leftPhaseMin;
            app.LineDataTable.Data{4,2} = app.lens.mLines{app.SamplingLineSpinner.Value}.leftPhaseMax;
        end

        % Button pushed function: PickCenterExclButton
        function PickCenterExclButtonPushed(app, event)
            if isnan(app.lens.mLines{app.SamplingLineSpinner.Value}.centerLine)
                app.addTwoConsole('Error: Set a center line first')
                return
            end
            app.addTwoConsole('Select starting point of center exclusion zone')
            app.listenForKey = true;
            h1 = drawcrosshair('Parent',app.UIAxes,'Position',[50 50],'LineWidth',1,'Color','k');
            uiwait(app.UIFigure)
            xs = h1.Position(1);
            if isequal(app.keyIn,'q')
                delete(h1)
                return
            end
            delete(h1)
            if xs >= app.lens.mLines{app.SamplingLineSpinner.Value}.centerLine
                app.addTwoConsole('Error: The center exclusion zone must be betwen the center of the lens and the lens edge')
                return
            end
            app.lens.mLines{app.SamplingLineSpinner.Value}.centerZone = xs;
            app.uppdateApp()
        end

        % Value changed function: NormalizedDataCheckBox
        function NormalizedDataCheckBoxValueChanged(app, event)
            app.uppdateApp()            
        end

        % Value changed function: ShowPeaks
        function ShowPeaksValueChanged(app, event)
            if isempty(app.lens.mLines{app.SamplingLineSpinner.Value}.gaussPks)
                app.ShowPeaks.Value = false;
                app.addTwoConsole('Error: Peaks have not been calculated')
                return
            end
            app.uppdateApp()
        end

        % Value changed function: ShowalllayersCheckBox
        function ShowalllayersCheckBoxValueChanged(app, event)
            app.uppdateApp()           
        end

        % Value changed function: ShowLines
        function ShowLinesValueChanged(app, event)
            app.uppdateApp()
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.uppdateApp()
        end

        % Button pushed function: ImportCalibrationImageButton
        function ImportCalibrationImageButtonPushed(app, event)
            [filename, path] = uigetfile('*');
            filePath = strcat(path,filename);
            I = imread(filePath);
            app.lens.calibIm = I;
            imshow(I,'Parent',app.UIAxes)
        end

        % Button pushed function: CalibrateImageButton
        function CalibrateImageButtonPushed(app, event)
            app.addTwoConsole('Draw a line in the image for a known distance.  Press "enter" when done or "Q" to cansel')
            roiLine = drawline(app.UIAxes);
            app.listenForKey = true;
            uiwait(app.UIFigure)
            if isequal(app.keyIn,'return')
                app.calibrationLine = roiLine.Position;
                app.lens.calibLine = roiLine.Position;
                app.addTwoConsole('Enter known distance to complete calibration')
            else
                delete(roiLine)
            end
            app.keyIn = '';
        end

        % Value changed function: CalibDist
        function CalibDistValueChanged(app, event)
            value = app.CalibDist.Value;
            if isnan(app.calibrationLine)
                app.addTwoConsole('Error: You need to import and calibrate an image first')
            else
                lineDist = norm(app.calibrationLine(2,:)-app.calibrationLine(1,:));
                mScale = 10^-6;
                app.lens.pixelSize = (value*mScale)/lineDist;
                app.lens.calibKnownDistance = value*mScale;
                app.PixelSize.Value = app.lens.pixelSize;
                for lind = 1:length(app.lens.mLines)
                    if ~isempty(app.lens.mLines{lind})
                        app.lens.mLines{lind}.pixelSize = app.lens.pixelSize;
                    end
                end
            end
        end

        % Selection changed function: ShowPlotButtonGroup
        function ShowPlotButtonGroupSelectionChanged(app, event)
            if app.PhaseGradientButton.Value && isempty(app.lens.mLines{app.SamplingLineSpinner.Value}.PP)
                app.addTwoConsole('Error: No peaks have been selected')
                app.PeakTraceButton.Value = true;
                return
            end
            app.uppdateApp()
        end

        % Button pushed function: SelectPeaksButton
        function SelectPeaksButtonPushed(app, event)
            if isempty(app.lens.mLines{app.SamplingLineSpinner.Value}.PD)
                if app.DistanceThresholdEditField.Value == 0 || app.MinPeakNumberEditField.Value == 0 
                    app.addTwoConsole('Error: You must set threshold values for the tracing function')
                    return
                end
            end
            app.selectPeaks()
            app.uppdateApp()

%             app.lens.mLines{app.SamplingLineSpinner.Value}.tracePeaks(app.DistanceThresholdEditField.Value, app.MinPeakNumberEditField.Value)
        end

        % Cell edit callback: PhaseTable
        function PhaseTableCellEdit(app, event)
            inds = event.Indices;
            if inds(2) == 4
                % change phase and call for an uppdate of the phase values.
                pind = app.PhaseTable.Data{inds(1),2};
                val = app.PhaseTable.Data{inds(1),inds(2)};
                serie = app.PhaseTable.Data{inds(1),1};
                app.lens.mLines{app.SamplingLineSpinner.Value}.PP(pind) = val;
                app.estimatePhase(serie)
            elseif inds(2) == 5
                % Change inlcude
                pind = app.PhaseTable.Data{inds(1),2};
                val = app.PhaseTable.Data{inds(1),inds(2)};
                app.lens.mLines{app.SamplingLineSpinner.Value}.Pinc(pind) = val;
            end
            app.uppdateApp()
        end

        % Button pushed function: ClearAllPeaksButton
        function ClearAllPeaksButtonPushed(app, event)
            if ~isempty(app.lens.mLines{app.SamplingLineSpinner.Value}.PD)
                answer = uiconfirm(app.UIFigure,'This will clear all traced peaks Continue?','Normalize points','Icon','warning');
                if isequal(answer,'Cancel')
                    return
                end
            end
            app.lens.mLines{app.SamplingLineSpinner.Value}.clearTracedPeaks()
            app.PhaseTable.Data = table();
            app.uppdateApp()
        end

        % Button pushed function: TestStopButton
        function TestStopButtonPushed(app, event)
            % This button does nothing except pauses the program in order
            % to evaluate variables inside.
            test = 1;            
        end

        % Button pushed function: SetSavGolayZoneButton
        function SetSavGolayZoneButtonPushed(app, event)
            app.addTwoConsole('Drag line to desirered point and press enter when done (q to quit)')
            app.listenForKey = true;
            h1 = drawcrosshair('Parent',app.UIAxes,'Position',[50 50],'LineWidth',1,'Color','k');
            uiwait(app.UIFigure)            
            if isequal(app.keyIn,'q')
                delete(h1)
                return
            end
            x = h1.Position(1);
            delete(h1)
            if x < app.lens.mLines{app.SamplingLineSpinner.Value}.leftEdge || x > app.lens.mLines{app.SamplingLineSpinner.Value}.centerLine
                app.lens.mLines{app.SamplingLineSpinner.Value}.sgolayEdge = 0;
                return
            end
            app.lens.mLines{app.SamplingLineSpinner.Value}.sgolayEdge = x;
            app.SavGolayZone.Value = x;
            app.lens.mLines{app.SamplingLineSpinner.Value}.findPeaks()
            app.uppdateApp()
        end

        % Value changed function: SavGolayZone
        function SavGolayZoneValueChanged(app, event)
            app.lens.mLines{app.SamplingLineSpinner.Value}.sgolayEdge = app.SavGolayZone.Value;
            app.lens.mLines{app.SamplingLineSpinner.Value}.findPeaks()
            app.uppdateApp()
        end

        % Button pushed function: ClearOutside
        function ClearOutsideButtonPushed(app, event)
            app.clearOustidePeaks()
            app.uppdateApp()
        end

        % Value changed function: LayerPhaseShift
        function LayerPhaseShiftValueChanged(app, event)
            value = app.LayerPhaseShift.Value;
            app.lens.mLines{app.SamplingLineSpinner.Value}.layerPhaseStep = value;
        end

        % Button pushed function: CalculateRefractiveIndexButton
        function CalculateRefractiveIndexButtonPushed(app, event)
            % Create wait popup
            if isnan(app.lens.mLines{app.SamplingLineSpinner.Value}.n0)
                app.addTwoConsole('Error: You must give a value for the outside refractie index')
                return
            end
            if isnan(app.lens.mLines{app.SamplingLineSpinner.Value}.lambda)
                app.addTwoConsole('Error: You must give a value for the wavlength used')
                return
            end
            app.lens.mLines{app.SamplingLineSpinner.Value}.AppCalculateRefractiveIndex()
            app.SamplingLineTable.Data{app.SamplingLineSpinner.Value,3} = true;
            app.lens.AppUppdateLineData(app.SamplingLineTable.Data)
            app.uppdateApp()
        end

        % Value changed function: WavelengthnmEditField_2
        function WavelengthnmEditField_2ValueChanged(app, event)
            app.lens.mLines{app.SamplingLineSpinner.Value}.lambda = app.WavelengthnmEditField_2.Value.*10^-9;
        end

        % Value changed function: OutsiderefractiveindexEditField_2
        function OutsiderefractiveindexEditField_2ValueChanged(app, event)
             app.lens.mLines{app.SamplingLineSpinner.Value}.n0 = app.OutsiderefractiveindexEditField_2.Value;           
        end

        % Button pushed function: FitPhaseProfileButton
        function FitPhaseProfileButtonPushed(app, event)
            if app.PhaseGradientButton.Value
                if app.expoFitButton.Value
                    fittype = 'power2';
            elseif app.polyFitButton.Value
                switch app.PolynomialgradeDropDown.Value
                    case '2'
                        fittype = 'poly2';
                    case '4'
                        fittype = 'poly4';
                    case '6'
                        fittype = 'poly6';
                end
                end
                if app.UserestraintsCheckBox.Value
                    restr = [app.LowerRestrField.Value,app.UpperRestrField.Value] ;
                else
                    restr = [-inf, inf];
                end
                app.lens.mLines{app.SamplingLineSpinner.Value}.AppFitPhaseShiftProfile(fittype,restr)
                app.uppdateApp()
            end            
        end

        % Button pushed function: RecalculateOverlayButton
        function RecalculateOverlayButtonPushed(app, event)
            app.lens.AppUppdateLineData(app.SamplingLineTable.Data)
            app.lens.AppInterpolateRefractiveIndex()
            app.RefractiveIndexOverlayCheckBox.Value = true;
            app.uppdateApp()
        end

        % Value changed function: RefractiveIndexOverlayCheckBox
        function RefractiveIndexOverlayCheckBoxValueChanged(app, event)
            app.uppdateApp()
        end

        % Button pushed function: PickEdgeExclButton
        function PickEdgeExclButtonPushed(app, event)
            if isnan(app.lens.mLines{app.SamplingLineSpinner.Value}.centerLine)
                app.addTwoConsole('Error: Set a center line first')
                return
            end
            app.addTwoConsole('Select edge exclusion zone')
            app.listenForKey = true;
            h1 = drawcrosshair('Parent',app.UIAxes,'Position',[50 50],'LineWidth',1,'Color','k');
            uiwait(app.UIFigure)
            xs = h1.Position(1);
            if isequal(app.keyIn,'q')
                delete(h1)
                return
            end
            delete(h1)
            if xs <= app.lens.mLines{app.SamplingLineSpinner.Value}.leftEdge
                app.addTwoConsole('Error: The edge exclusion zone must be betwen the center of the lens and the lens edge')
                return
            end
            app.lens.mLines{app.SamplingLineSpinner.Value}.edgeZone = xs;
            app.uppdateApp()
        end

        % Value changed function: WavelengthnmEditField
        function WavelengthnmEditFieldValueChanged(app, event)
            app.lens.lambda = app.WavelengthnmEditField.Value;
        end

        % Value changed function: OutsiderefractiveindexEditField
        function OutsiderefractiveindexEditFieldValueChanged(app, event)
            app.lens.n0 = app.OutsiderefractiveindexEditField.Value;
        end

        % Button pushed function: GetValuesFromStackButton
        function GetValuesFromStackButtonPushed(app, event)
            if ~isnan(app.lens.lambda)
                app.WavelengthnmEditField_2.Value = app.lens.lambda;
                app.lens.mLines{app.SamplingLineSpinner.Value}.lambda = app.lens.lambda;
            end
            if ~isnan(app.lens.n0)
                app.OutsiderefractiveindexEditField_2.Value = app.lens.n0;
                app.lens.mLines{app.SamplingLineSpinner.Value}.n0 = app.lens.n0;
            end
        end

        % Value changed function: PolynomialgradeDropDown
        function PolynomialgradeDropDownValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.HomeMenu = uimenu(app.UIFigure);
            app.HomeMenu.Text = 'Home';
            app.SaveProjectMenu = uimenu(app.HomeMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Text = 'Save Project';
            app.SaveProjectAsMenu = uimenu(app.HomeMenu);
            app.SaveProjectAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectAsMenuSelected, true);
            app.SaveProjectAsMenu.Text = 'Save Project As';
            app.LoadProjectMenu = uimenu(app.HomeMenu);
            app.LoadProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProjectMenuSelected, true);
            app.LoadProjectMenu.Text = 'Load Project';
            app.StackMenu = uimenu(app.UIFigure);
            app.StackMenu.Text = 'Stack';
            app.LineMenu = uimenu(app.UIFigure);
            app.LineMenu.Text = 'Line';
            app.FigureMenu = uimenu(app.UIFigure);
            app.FigureMenu.Text = 'Figure';
            app.SaveFigureMenu = uimenu(app.FigureMenu);
            app.SaveFigureMenu.Text = 'Save Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LayerSpinnerLabel = uilabel(app.UIFigure);
            app.LayerSpinnerLabel.Enable = 'off';
            app.LayerSpinnerLabel.Text = 'Layer';
            app.LayerSpinner = uispinner(app.UIFigure);
            app.LayerSpinner.ValueChangedFcn = createCallbackFcn(app, @LayerSpinnerValueChanged, true);
            app.LayerSpinner.Enable = 'off';
            app.SamplingLineSpinnerLabel = uilabel(app.UIFigure);
            app.SamplingLineSpinnerLabel.Enable = 'off';
            app.SamplingLineSpinnerLabel.Text = 'Sampling Line';
            app.SamplingLineSpinner = uispinner(app.UIFigure);
            app.SamplingLineSpinner.Limits = [0 Inf];
            app.SamplingLineSpinner.ValueChangedFcn = createCallbackFcn(app, @SamplingLineSpinnerValueChanged, true);
            app.SamplingLineSpinner.Enable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.TabGroup.Tag = 'calib';
            app.StackTab = uitab(app.TabGroup);
            app.StackTab.Title = 'Stack';
            app.StackTab.Tag = 'stack';
            app.ImportImagesButton = uibutton(app.StackTab, 'push');
            app.ImportImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImagesButtonPushed, true);
            app.ImportImagesButton.Text = 'Import Images';
            app.CropStackButton = uibutton(app.StackTab, 'push');
            app.CropStackButton.ButtonPushedFcn = createCallbackFcn(app, @CropStackButtonPushed, true);
            app.CropStackButton.Text = 'Crop Stack';
            app.ShowCropedIm = uicheckbox(app.StackTab);
            app.ShowCropedIm.ValueChangedFcn = createCallbackFcn(app, @ShowCropedImValueChanged, true);
            app.ShowCropedIm.Text = 'Show Cropped Image';
            app.ShowCropRec = uicheckbox(app.StackTab);
            app.ShowCropRec.ValueChangedFcn = createCallbackFcn(app, @ShowCropRecValueChanged, true);
            app.ShowCropRec.Text = 'Show Cropping Rectangle';
            app.CreateSamplingLineButton = uibutton(app.StackTab, 'push');
            app.CreateSamplingLineButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSamplingLineButtonPushed, true);
            app.CreateSamplingLineButton.Text = 'Create Sampling Line';
            app.SamplingLineTable = uitable(app.StackTab);
            app.SamplingLineTable.ColumnName = {'Ind'; 'radius'; 'simulated'; 'include'};
            app.SamplingLineTable.RowName = {};
            app.SamplingLineTable.ColumnEditable = [false false false true];
            app.SamplingLineTable.CellSelectionCallback = createCallbackFcn(app, @SamplingLineTableCellSelection, true);
            app.SamplingLinesLabel = uilabel(app.StackTab);
            app.SamplingLinesLabel.Text = 'Sampling Lines';
            app.CreateSupportLineButton = uibutton(app.StackTab, 'push');
            app.CreateSupportLineButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSupportLineButtonPushed, true);
            app.CreateSupportLineButton.Text = 'Create Support Line';
            app.SupportLineTable = uitable(app.StackTab);
            app.SupportLineTable.ColumnName = {'Ind'; 'comment'};
            app.SupportLineTable.RowName = {};
            app.SupportLineTable.ColumnEditable = [false true];
            app.SupportLineTable.CellSelectionCallback = createCallbackFcn(app, @SupportLineTableCellSelection, true);
            app.SupportLinesLabel = uilabel(app.StackTab);
            app.SupportLinesLabel.Text = 'Support Lines';
            app.ShowLines = uicheckbox(app.StackTab);
            app.ShowLines.ValueChangedFcn = createCallbackFcn(app, @ShowLinesValueChanged, true);
            app.ShowLines.Text = 'Show Lines';
            app.WavelengthnmEditFieldLabel = uilabel(app.StackTab);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength  [nm]';
            app.WavelengthnmEditField = uieditfield(app.StackTab, 'numeric');
            app.WavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmEditFieldValueChanged, true);
            app.OutsiderefractiveindexEditFieldLabel = uilabel(app.StackTab);
            app.OutsiderefractiveindexEditFieldLabel.Text = 'Outside refractive index';
            app.OutsiderefractiveindexEditField = uieditfield(app.StackTab, 'numeric');
            app.OutsiderefractiveindexEditField.ValueChangedFcn = createCallbackFcn(app, @OutsiderefractiveindexEditFieldValueChanged, true);
            app.RefractiveIndexOverlayCheckBox = uicheckbox(app.StackTab);
            app.RefractiveIndexOverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @RefractiveIndexOverlayCheckBoxValueChanged, true);
            app.RefractiveIndexOverlayCheckBox.Text = 'Refractive Index Overlay';
            app.RecalculateOverlayButton = uibutton(app.StackTab, 'push');
            app.RecalculateOverlayButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculateOverlayButtonPushed, true);
            app.RecalculateOverlayButton.Text = 'Recalculate Overlay';
            app.LineTab = uitab(app.TabGroup);
            app.LineTab.Title = 'Line';
            app.LineTab.Tag = 'line';
            app.LineInterpGroup = uibuttongroup(app.LineTab);
            app.LineInterpGroup.SelectionChangedFcn = createCallbackFcn(app, @LineInterpGroupSelectionChanged, true);
            app.LineInterpGroup.Title = 'Data Interpolation';
            app.RawButton = uiradiobutton(app.LineInterpGroup);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.GaussianButton = uiradiobutton(app.LineInterpGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.SavGoButton = uiradiobutton(app.LineInterpGroup);
            app.SavGoButton.Text = 'SavGo';
            app.AllButton = uiradiobutton(app.LineInterpGroup);
            app.AllButton.Text = 'All';
            app.LineDataTable = uitable(app.LineTab);
            app.LineDataTable.ColumnName = {'Variable'; 'Value'};
            app.LineDataTable.RowName = {};
            app.LineDataTable.ColumnEditable = [false true];
            app.LineDataTable.CellEditCallback = createCallbackFcn(app, @LineDataTableCellEdit, true);
            app.FlipDataButton = uibutton(app.LineTab, 'push');
            app.FlipDataButton.ButtonPushedFcn = createCallbackFcn(app, @FlipDataButtonPushed, true);
            app.FlipDataButton.Text = 'Flip Data';
            app.FindEdgeButton = uibutton(app.LineTab, 'push');
            app.FindEdgeButton.ButtonPushedFcn = createCallbackFcn(app, @FindEdgeButtonPushed, true);
            app.FindEdgeButton.Text = 'Find Edge';
            app.FindCenterButton = uibutton(app.LineTab, 'push');
            app.FindCenterButton.ButtonPushedFcn = createCallbackFcn(app, @FindCenterButtonPushed, true);
            app.FindCenterButton.Text = 'Find Center';
            app.FindPeaksButton = uibutton(app.LineTab, 'push');
            app.FindPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindPeaksButtonPushed, true);
            app.FindPeaksButton.Text = 'Find Peaks';
            app.FindEdgeMinButton = uibutton(app.LineTab, 'push');
            app.FindEdgeMinButton.ButtonPushedFcn = createCallbackFcn(app, @FindEdgeMinButtonPushed, true);
            app.FindEdgeMinButton.Text = 'Find Edge Min';
            app.NormalizeDataButton = uibutton(app.LineTab, 'push');
            app.NormalizeDataButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeDataButtonPushed, true);
            app.NormalizeDataButton.Text = 'Normalize Data';
            app.PlotOptionsPanel = uipanel(app.LineTab);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.NormalizedDataCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.NormalizedDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizedDataCheckBoxValueChanged, true);
            app.NormalizedDataCheckBox.Enable = 'off';
            app.NormalizedDataCheckBox.Text = 'Normalized Data';
            app.ShowalllayersCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.ShowalllayersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowalllayersCheckBoxValueChanged, true);
            app.ShowalllayersCheckBox.Text = 'Show all layers';
            app.ShowPeaks = uicheckbox(app.PlotOptionsPanel);
            app.ShowPeaks.ValueChangedFcn = createCallbackFcn(app, @ShowPeaksValueChanged, true);
            app.ShowPeaks.Text = 'Show Peaks';
            app.SavGolayZoneEditFieldLabel = uilabel(app.LineTab);
            app.SavGolayZoneEditFieldLabel.Text = 'SavGolay Zone';
            app.SavGolayZone = uieditfield(app.LineTab, 'numeric');
            app.SavGolayZone.ValueChangedFcn = createCallbackFcn(app, @SavGolayZoneValueChanged, true);
            app.SetSavGolayZoneButton = uibutton(app.LineTab, 'push');
            app.SetSavGolayZoneButton.ButtonPushedFcn = createCallbackFcn(app, @SetSavGolayZoneButtonPushed, true);
            app.SetSavGolayZoneButton.Text = 'Set SavGolay Zone';
            app.PhaseTab = uitab(app.TabGroup);
            app.PhaseTab.Title = 'Phase';
            app.PhaseTab.Tag = 'phase';
            app.ShowPlotButtonGroup = uibuttongroup(app.PhaseTab);
            app.ShowPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowPlotButtonGroupSelectionChanged, true);
            app.ShowPlotButtonGroup.Title = 'Show Plot';
            app.PeakTraceButton = uiradiobutton(app.ShowPlotButtonGroup);
            app.PeakTraceButton.Text = 'Peak Trace';
            app.PhaseGradientButton = uiradiobutton(app.ShowPlotButtonGroup);
            app.PhaseGradientButton.Text = 'Phase Gradient';
            app.PeakSelectButton = uiradiobutton(app.ShowPlotButtonGroup);
            app.PeakSelectButton.Text = 'Peak Select';
            app.PeakSelectButton.Value = true;
            app.RemovePeaksButton = uibutton(app.PhaseTab, 'push');
            app.RemovePeaksButton.Text = 'Remove Peaks';
            app.TracingthresholdsPanel = uipanel(app.PhaseTab);
            app.TracingthresholdsPanel.Title = 'Tracing thresholds';
            app.DistanceThresholdEditFieldLabel = uilabel(app.TracingthresholdsPanel);
            app.DistanceThresholdEditFieldLabel.Text = 'Distance Threshold';
            app.DistanceThresholdEditField = uieditfield(app.TracingthresholdsPanel, 'numeric');
            app.DistanceThresholdEditField.Value = 30;
            app.MinPeakNumberEditFieldLabel = uilabel(app.TracingthresholdsPanel);
            app.MinPeakNumberEditFieldLabel.Text = 'Min Peak Number';
            app.MinPeakNumberEditField = uieditfield(app.TracingthresholdsPanel, 'numeric');
            app.MinPeakNumberEditField.Value = 2;
            app.PhaseTable = uitable(app.PhaseTab);
            app.PhaseTable.ColumnName = {'Group'; 'P_ind'; 'Layer'; 'Phase Shift'; 'Include'};
            app.PhaseTable.RowName = {};
            app.PhaseTable.ColumnSortable = [true true true false false];
            app.PhaseTable.ColumnEditable = [false false false true true];
            app.PhaseTable.CellEditCallback = createCallbackFcn(app, @PhaseTableCellEdit, true);
            app.PickCenterExclButton = uibutton(app.PhaseTab, 'push');
            app.PickCenterExclButton.ButtonPushedFcn = createCallbackFcn(app, @PickCenterExclButtonPushed, true);
            app.PickCenterExclButton.Text = 'Pick Center Excl';
            app.PickEdgeExclButton = uibutton(app.PhaseTab, 'push');
            app.PickEdgeExclButton.ButtonPushedFcn = createCallbackFcn(app, @PickEdgeExclButtonPushed, true);
            app.PickEdgeExclButton.Text = 'Pick Edge Excl';
            app.CenterExclusionEditFieldLabel = uilabel(app.PhaseTab);
            app.CenterExclusionEditFieldLabel.Text = 'Center Exclusion';
            app.CenterExclusionEditField = uieditfield(app.PhaseTab, 'numeric');
            app.EdgeExclusionEditFieldLabel = uilabel(app.PhaseTab);
            app.EdgeExclusionEditFieldLabel.Text = 'Edge Exclusion';
            app.EdgeExclusionEditField = uieditfield(app.PhaseTab, 'numeric');
            app.SelectPeaksButton = uibutton(app.PhaseTab, 'push');
            app.SelectPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPeaksButtonPushed, true);
            app.SelectPeaksButton.Text = 'Select Peaks';
            app.ClearAllPeaksButton = uibutton(app.PhaseTab, 'push');
            app.ClearAllPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllPeaksButtonPushed, true);
            app.ClearAllPeaksButton.Text = 'Clear All Peaks';
            app.PhaseshiftforeachlayerLabel = uilabel(app.PhaseTab);
            app.PhaseshiftforeachlayerLabel.Text = 'Phase shift for each layer';
            app.LayerPhaseShift = uieditfield(app.PhaseTab, 'numeric');
            app.LayerPhaseShift.ValueChangedFcn = createCallbackFcn(app, @LayerPhaseShiftValueChanged, true);
            app.ClearOutside = uibutton(app.PhaseTab, 'push');
            app.ClearOutside.ButtonPushedFcn = createCallbackFcn(app, @ClearOutsideButtonPushed, true);
            app.ClearOutside.Text = 'Clear peaks outside span';
            app.FitPhaseProfileButton = uibutton(app.PhaseTab, 'push');
            app.FitPhaseProfileButton.ButtonPushedFcn = createCallbackFcn(app, @FitPhaseProfileButtonPushed, true);
            app.FitPhaseProfileButton.Text = 'Fit Phase Profile';
            app.FitTypeButtonGroup = uibuttongroup(app.PhaseTab);
            app.FitTypeButtonGroup.Title = 'Fit Type';
            app.polyFitButton = uiradiobutton(app.FitTypeButtonGroup);
            app.polyFitButton.Text = 'polyFit';
            app.polyFitButton.Value = true;
            app.expoFitButton = uiradiobutton(app.FitTypeButtonGroup);
            app.expoFitButton.Text = 'expoFit';
            app.LowerLabel = uilabel(app.PhaseTab);
            app.LowerLabel.Text = 'Lower';
            app.LowerRestrField = uieditfield(app.PhaseTab, 'numeric');
            app.LowerRestrField.Value = -Inf;
            app.UserestraintsCheckBox = uicheckbox(app.PhaseTab);
            app.UserestraintsCheckBox.Text = 'Use restraints';
            app.UpperEditFieldLabel = uilabel(app.PhaseTab);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperRestrField = uieditfield(app.PhaseTab, 'numeric');
            app.UpperRestrField.Value = Inf;
            app.MaxLayerEditFieldLabel = uilabel(app.PhaseTab);
            app.MaxLayerEditFieldLabel.Text = 'Max Layer';
            app.MaxLayerEditField = uieditfield(app.PhaseTab, 'numeric');
            app.MaxLayerEditField.Editable = 'off';
            app.MinLayerEditFieldLabel = uilabel(app.PhaseTab);
            app.MinLayerEditFieldLabel.Text = 'Min Layer';
            app.MinLayerEditField = uieditfield(app.PhaseTab, 'numeric');
            app.MinLayerEditField.Editable = 'off';
            app.PolynomialgradeDropDownLabel = uilabel(app.PhaseTab);
            app.PolynomialgradeDropDownLabel.Text = 'Polynomial grade';
            app.PolynomialgradeDropDown = uidropdown(app.PhaseTab);
            app.PolynomialgradeDropDown.Items = {'2', '4', '6', ''};
            app.PolynomialgradeDropDown.ValueChangedFcn = createCallbackFcn(app, @PolynomialgradeDropDownValueChanged, true);
            app.PolynomialgradeDropDown.Value = '2';
            app.nIndexTab = uitab(app.TabGroup);
            app.nIndexTab.Title = 'n-Index';
            app.nIndexTab.Tag = 'nindex';
            app.WavelengthnmEditField_2Label = uilabel(app.nIndexTab);
            app.WavelengthnmEditField_2Label.Text = 'Wavelength  [nm]';
            app.WavelengthnmEditField_2 = uieditfield(app.nIndexTab, 'numeric');
            app.WavelengthnmEditField_2.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmEditField_2ValueChanged, true);
            app.OutsiderefractiveindexEditField_2Label = uilabel(app.nIndexTab);
            app.OutsiderefractiveindexEditField_2Label.Text = 'Outside refractive index';
            app.OutsiderefractiveindexEditField_2 = uieditfield(app.nIndexTab, 'numeric');
            app.OutsiderefractiveindexEditField_2.ValueChangedFcn = createCallbackFcn(app, @OutsiderefractiveindexEditField_2ValueChanged, true);
            app.CalculateRefractiveIndexButton = uibutton(app.nIndexTab, 'push');
            app.CalculateRefractiveIndexButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRefractiveIndexButtonPushed, true);
            app.CalculateRefractiveIndexButton.Text = 'Calculate Refractive Index';
            app.GetValuesFromStackButton = uibutton(app.nIndexTab, 'push');
            app.GetValuesFromStackButton.ButtonPushedFcn = createCallbackFcn(app, @GetValuesFromStackButtonPushed, true);
            app.GetValuesFromStackButton.Text = 'Get Values From Stack';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.CalibrationTab.Tag = 'calibration';
            app.ImportCalibrationImageButton = uibutton(app.CalibrationTab, 'push');
            app.ImportCalibrationImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportCalibrationImageButtonPushed, true);
            app.ImportCalibrationImageButton.Text = 'Import Calibration Image';
            app.CalibrateImageButton = uibutton(app.CalibrationTab, 'push');
            app.CalibrateImageButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateImageButtonPushed, true);
            app.CalibrateImageButton.Text = 'Calibrate Image';
            app.DistanceumEditFieldLabel = uilabel(app.CalibrationTab);
            app.DistanceumEditFieldLabel.Text = 'Distance [um]';
            app.CalibDist = uieditfield(app.CalibrationTab, 'numeric');
            app.CalibDist.ValueChangedFcn = createCallbackFcn(app, @CalibDistValueChanged, true);
            app.PixelSizeEditFieldLabel = uilabel(app.CalibrationTab);
            app.PixelSizeEditFieldLabel.Text = 'Pixel Size';
            app.PixelSize = uieditfield(app.CalibrationTab, 'numeric');
            app.PixelSize.Editable = 'off';
            app.ToolMenuLabel = uilabel(app.UIFigure);
            app.ToolMenuLabel.Text = 'Tool Menu';
            app.TotalLayers = uilabel(app.UIFigure);
            app.TotalLayers.Text = 'Total: 00';
            app.Console = uitextarea(app.UIFigure);
            app.Console.Editable = 'off';
            app.TestStopButton = uibutton(app.UIFigure, 'push');
            app.TestStopButton.ButtonPushedFcn = createCallbackFcn(app, @TestStopButtonPushed, true);
            app.TestStopButton.Text = 'Test Stop';
            app.SampLineTableMenue = uicontextmenu(app.UIFigure);
            app.DeleteSelectedLineMenu = uimenu(app.SampLineTableMenue);
            app.DeleteSelectedLineMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteSelectedLineMenuSelected, true);
            app.DeleteSelectedLineMenu.Text = 'Delete Selected Line';
            % Assign app.SampLineTableMenue
            app.SamplingLineTable.ContextMenu = app.SampLineTableMenue;
            app.SupLinesTableMenue = uicontextmenu(app.UIFigure);
            app.DeleteSelectedSupLineMenu = uimenu(app.SupLinesTableMenue);
            app.DeleteSelectedSupLineMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteSelectedSupLineMenuSelected, true);
            app.DeleteSelectedSupLineMenu.Text = 'Delete Selected Line';
            % Assign app.SupLinesTableMenue
            app.SupportLineTable.ContextMenu = app.SupLinesTableMenue;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GrinCalcApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end