classdef Delete_DialogBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DeleteLabel              matlab.ui.control.Label
        TextArea                 matlab.ui.control.Label
        YesButton                matlab.ui.control.Button
        NoButton                 matlab.ui.control.Button
        DonotaskmeagainCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CallingApp % Main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, text_val)
            app.CallingApp = mainapp;
            app.TextArea.Text = strcat('Are you shure you want to delete the',{' '},text_val,'?');         
            app.DeleteLabel.Text = strcat('Delete',{' '},text_val);
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            app.CallingApp.delete_val = 1;
            delete(app)
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.CallingApp.delete_val = 0;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DeleteLabel = uilabel(app.UIFigure);
            app.DeleteLabel.Text = 'Delete';
            app.TextArea = uilabel(app.UIFigure);
            app.TextArea.Text = 'Are you shure you want to delete ';
            app.YesButton = uibutton(app.UIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.UIFigure, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.DonotaskmeagainCheckBox = uicheckbox(app.UIFigure);
            app.DonotaskmeagainCheckBox.Text = 'Do not ask me again';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Delete_DialogBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end