classdef main_GUI_v01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SaveMenu_2                   matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        SaveAsMenu                   matlab.ui.container.Menu
        ExportMenu                   matlab.ui.container.Menu
        ExportRefactiveIndexMapMenu  matlab.ui.container.Menu
        ExportRefractiveIndexMatrixMenu  matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        ModelsetupTab                matlab.ui.container.Tab
        LoadimageButton              matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        NewselectionButton           matlab.ui.control.Button
        EditselectionButton          matlab.ui.control.Button
        DeleteSelectionButton        matlab.ui.control.Button
        ShowselectionButton          matlab.ui.control.Button
        InputparametersLabel         matlab.ui.control.Label
        SelectionsListBoxLabel       matlab.ui.control.Label
        SelectionsListBox            matlab.ui.control.ListBox
        n_0EditFieldLabel            matlab.ui.control.Label
        n_0EditField                 matlab.ui.control.NumericEditField
        hEditFieldLabel              matlab.ui.control.Label
        hEditField                   matlab.ui.control.NumericEditField
        rEditFieldLabel              matlab.ui.control.Label
        rEditField                   matlab.ui.control.NumericEditField
        ListBoxLabel                 matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
        SelectionTab                 matlab.ui.container.Tab
        UIAxes2                      matlab.ui.control.UIAxes
        ShoworiginalselectionButton  matlab.ui.control.Button
        FindCenterButton             matlab.ui.control.Button
        ManualcenterselectionButton  matlab.ui.control.Button
        FindPeaksButton              matlab.ui.control.Button
        ManualpeakselectionButton    matlab.ui.control.Button
        RemovePeaksButton            matlab.ui.control.Button
        RayTraceTab                  matlab.ui.container.Tab
        UIAxes3                      matlab.ui.control.UIAxes
        RefractiveIndexMapTab        matlab.ui.container.Tab
        UIAxes4                      matlab.ui.control.UIAxes
        OutputTextAreaLabel          matlab.ui.control.Label
        OutputTextArea               matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        line_selections_coordinates = {}; % Coordinates for the line selections
        I = nan; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            [filename, path] = uigetfile('*');
            filePath = strcat(path,filename);
            app.I = imread(filePath);
            imshow(app.I,'Parent',app.UIAxes)
        end

        % Button pushed function: NewselectionButton
        function NewselectionButtonPushed(app, event)
            if isnan(app.I)
                app.OutputTextArea.Value = 'Error: No image selected';
            else
                app.OutputTextArea.Value = 'Press and hold left mouse button to create selection line';
                n = length(app.SelectionsListBox.Items);
                if n == 0
                    app.SelectionsListBox.Items = {'line1'};
                else
                    app.SelectionsListBox.Items{n+1} = strcat('line ',num2str(n+1));
                end
                h1 = drawline(app.UIAxes,'Color','b','HandleVisibility','off','InteractionsAllowed','none','LineWidth',1);
                app.SelectionsListBox.ItemsData{n+1} = h1;                
                app.line_selections_coordinates{n+1} = h1.Position;
                
            end
        end

        % Value changed function: SelectionsListBox
        function SelectionsListBoxValueChanged(app, event)
            
            value = app.SelectionsListBox.Items;
            app.OutputTextArea.Value = string(value.Position);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.OutputTextArea.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaveMenu_2 = uimenu(app.UIFigure);
            app.SaveMenu_2.Text = 'Save';
            app.SaveMenu = uimenu(app.SaveMenu_2);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.SaveMenu_2);
            app.SaveAsMenu.Text = 'Save As';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.ExportRefactiveIndexMapMenu = uimenu(app.ExportMenu);
            app.ExportRefactiveIndexMapMenu.Text = 'Export Refactive Index Map';
            app.ExportRefractiveIndexMatrixMenu = uimenu(app.ExportMenu);
            app.ExportRefractiveIndexMatrixMenu.Text = 'Export Refractive Index Matrix';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ModelsetupTab = uitab(app.TabGroup);
            app.ModelsetupTab.Title = 'Model setup';
            app.LoadimageButton = uibutton(app.ModelsetupTab, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.UIAxes = uiaxes(app.ModelsetupTab);
            title(app.UIAxes, 'Input Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.NewselectionButton = uibutton(app.ModelsetupTab, 'push');
            app.NewselectionButton.ButtonPushedFcn = createCallbackFcn(app, @NewselectionButtonPushed, true);
            app.NewselectionButton.Text = 'New selection';
            app.EditselectionButton = uibutton(app.ModelsetupTab, 'push');
            app.EditselectionButton.Text = 'Edit selection';
            app.DeleteSelectionButton = uibutton(app.ModelsetupTab, 'push');
            app.DeleteSelectionButton.Text = 'Delete Selection';
            app.ShowselectionButton = uibutton(app.ModelsetupTab, 'push');
            app.ShowselectionButton.Text = 'Show selection';
            app.InputparametersLabel = uilabel(app.ModelsetupTab);
            app.InputparametersLabel.Text = 'Input parameters';
            app.SelectionsListBoxLabel = uilabel(app.ModelsetupTab);
            app.SelectionsListBoxLabel.Text = 'Selections';
            app.SelectionsListBox = uilistbox(app.ModelsetupTab);
            app.SelectionsListBox.Items = {};
            app.SelectionsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectionsListBoxValueChanged, true);
            app.SelectionsListBox.Tag = 'selection_list';
            app.SelectionsListBox.Value = {};
            app.n_0EditFieldLabel = uilabel(app.ModelsetupTab);
            app.n_0EditFieldLabel.Text = 'n_0';
            app.n_0EditField = uieditfield(app.ModelsetupTab, 'numeric');
            app.hEditFieldLabel = uilabel(app.ModelsetupTab);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.ModelsetupTab, 'numeric');
            app.rEditFieldLabel = uilabel(app.ModelsetupTab);
            app.rEditFieldLabel.Text = 'Γ_r';
            app.rEditField = uieditfield(app.ModelsetupTab, 'numeric');
            app.ListBoxLabel = uilabel(app.ModelsetupTab);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.ModelsetupTab);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Item 2';
            app.SelectionTab = uitab(app.TabGroup);
            app.SelectionTab.Title = 'Selection';
            app.UIAxes2 = uiaxes(app.SelectionTab);
            title(app.UIAxes2, 'Selection ')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.ShoworiginalselectionButton = uibutton(app.SelectionTab, 'push');
            app.ShoworiginalselectionButton.Text = 'Show original selection';
            app.FindCenterButton = uibutton(app.SelectionTab, 'push');
            app.FindCenterButton.Text = 'Find Center';
            app.ManualcenterselectionButton = uibutton(app.SelectionTab, 'push');
            app.ManualcenterselectionButton.Text = 'Manual center selection';
            app.FindPeaksButton = uibutton(app.SelectionTab, 'push');
            app.FindPeaksButton.Text = 'Find Peaks';
            app.ManualpeakselectionButton = uibutton(app.SelectionTab, 'push');
            app.ManualpeakselectionButton.Text = 'Manual peak selection';
            app.RemovePeaksButton = uibutton(app.SelectionTab, 'push');
            app.RemovePeaksButton.Text = 'Remove Peaks';
            app.RayTraceTab = uitab(app.TabGroup);
            app.RayTraceTab.Title = 'Ray-Trace';
            app.UIAxes3 = uiaxes(app.RayTraceTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.RefractiveIndexMapTab = uitab(app.TabGroup);
            app.RefractiveIndexMapTab.Title = 'Refractive Index Map';
            app.UIAxes4 = uiaxes(app.RefractiveIndexMapTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_GUI_v01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end