classdef main_GUI_v02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        EditMenu                   matlab.ui.container.Menu
        ObjectBrowserPanel         matlab.ui.container.Panel
        DetailsTextAreaLabel       matlab.ui.control.Label
        DetailsTextArea            matlab.ui.control.TextArea
        Tree                       matlab.ui.container.Tree
        ImportedimagesNode         matlab.ui.container.TreeNode
        LineSelectionsNode         matlab.ui.container.TreeNode
        TabGroup                   matlab.ui.container.TabGroup
        modelsetupTab              matlab.ui.container.Tab
        ToolsPanel                 matlab.ui.container.Panel
        ImportImageButton          matlab.ui.control.Button
        SelectLineButton           matlab.ui.control.Button
        DeleteButton               matlab.ui.control.Button
        view_setup                 matlab.ui.control.UIAxes
        peakselectionTab           matlab.ui.container.Tab
        ToolsPanel_2               matlab.ui.container.Panel
        UIAxes2_2                  matlab.ui.control.UIAxes
        raytracingTab              matlab.ui.container.Tab
        ToolsPanel_3               matlab.ui.container.Panel
        UIAxes2_3                  matlab.ui.control.UIAxes
        refractiveindexmapTab      matlab.ui.container.Tab
        ToolsPanel_4               matlab.ui.container.Panel
        UIAxes2_4                  matlab.ui.control.UIAxes
        OutputwindowTextAreaLabel  matlab.ui.control.Label
        Out_text                   matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        DialogApp % Dialog box app
        n_images = 0;
        n_lines = 0;
        refractive_I0 = 0;      % refractive index r0 (r-index of surounding medium)
        selected_image = 0;
        selected_line = 0;
    end
    
    properties (Access = public)
        delete_val = 'nothing has changed'; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
            [filename, path] = uigetfile('*');
            filePath = strcat(path,filename);
            I = imread(filePath);
            app.n_images = app.n_images + 1;
            imshow(I,'Parent',app.view_setup)
            uitreenode(app.ImportedimagesNode,'Text',strcat('image',num2str(app.n_images)),'NodeData',I);
            expand(app.ImportedimagesNode)
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNode = app.Tree.SelectedNodes;
            nodeParent = selectedNode.Parent;
            if ~isempty(selectedNode.NodeData)
                if isequal(nodeParent.Text,'Imported images')
                    imshow(selectedNode.NodeData,'Parent',app.view_setup)
                    app.Out_text.Value = 'this is an imported image';
                elseif isequal(nodeParent.Text,'Line Selections')
                    app.Out_text.Value = 'this is a line selection';
                end
            else
                % app.Out_text.Value = 'node empty';
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
%             app.OptionsButton.Enable = 'off';
            send_val = 'test';
            app.DialogApp = Delete_DialogBox(app,send_val);
            app.Out_text.Value = app.delete_val;
            app.Out_text.Value = 'stuff happend';
        end

        % Button pushed function: SelectLineButton
        function SelectLineButtonPushed(app, event)
            if app.n_images == 0 
                app.Out_text.Value = 'Error: No image selected';
                return
            end
            roi = drawline(app.view_setup);
            app.n_lines = app.n_lines + 1;
            uitreenode(app.LineSelectionsNode,'Text',strcat('line',num2str(app.n_lines)),'NodeData',{roi,app.selected_line});
            expand(app.LineSelectionsNode);
            app.selected_line = app.n_lines;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.ObjectBrowserPanel = uipanel(app.UIFigure);
            app.ObjectBrowserPanel.Title = 'Object Browser';
            app.DetailsTextAreaLabel = uilabel(app.ObjectBrowserPanel);
            app.DetailsTextAreaLabel.Text = 'Details';
            app.DetailsTextArea = uitextarea(app.ObjectBrowserPanel);
            app.Tree = uitree(app.ObjectBrowserPanel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.ImportedimagesNode = uitreenode(app.Tree);
            app.ImportedimagesNode.Text = 'Imported images';
            app.LineSelectionsNode = uitreenode(app.Tree);
            app.LineSelectionsNode.Text = 'Line Selections';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.modelsetupTab = uitab(app.TabGroup);
            app.modelsetupTab.Title = 'model setup';
            app.ToolsPanel = uipanel(app.modelsetupTab);
            app.ToolsPanel.Title = 'Tools';
            app.ImportImageButton = uibutton(app.ToolsPanel, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Text = 'Import Image';
            app.SelectLineButton = uibutton(app.ToolsPanel, 'push');
            app.SelectLineButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLineButtonPushed, true);
            app.SelectLineButton.Text = 'Select Line';
            app.DeleteButton = uibutton(app.ToolsPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.view_setup = uiaxes(app.modelsetupTab);
            title(app.view_setup, '')
            xlabel(app.view_setup, '')
            ylabel(app.view_setup, '')
            app.peakselectionTab = uitab(app.TabGroup);
            app.peakselectionTab.Title = 'peak selection';
            app.ToolsPanel_2 = uipanel(app.peakselectionTab);
            app.ToolsPanel_2.Title = 'Tools';
            app.UIAxes2_2 = uiaxes(app.peakselectionTab);
            title(app.UIAxes2_2, 'Viewing window')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            app.UIAxes2_2.XLim = [0 1];
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.raytracingTab = uitab(app.TabGroup);
            app.raytracingTab.Title = 'ray-tracing';
            app.ToolsPanel_3 = uipanel(app.raytracingTab);
            app.ToolsPanel_3.Title = 'Tools';
            app.UIAxes2_3 = uiaxes(app.raytracingTab);
            title(app.UIAxes2_3, 'Viewing window')
            xlabel(app.UIAxes2_3, '')
            ylabel(app.UIAxes2_3, '')
            app.UIAxes2_3.XLim = [0 1];
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.refractiveindexmapTab = uitab(app.TabGroup);
            app.refractiveindexmapTab.Title = 'refractive index map';
            app.ToolsPanel_4 = uipanel(app.refractiveindexmapTab);
            app.ToolsPanel_4.Title = 'Tools';
            app.UIAxes2_4 = uiaxes(app.refractiveindexmapTab);
            title(app.UIAxes2_4, 'Viewing window')
            xlabel(app.UIAxes2_4, '')
            ylabel(app.UIAxes2_4, '')
            app.UIAxes2_4.XLim = [0 1];
            app.UIAxes2_4.XTick = [];
            app.UIAxes2_4.YTick = [];
            app.OutputwindowTextAreaLabel = uilabel(app.UIFigure);
            app.OutputwindowTextAreaLabel.Text = 'Output window';
            app.Out_text = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_GUI_v02

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end